classdef app1 < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                matlab.ui.Figure
        TimeLeftEditField       matlab.ui.control.EditField
        TimeLeftEditFieldLabel  matlab.ui.control.Label
        PLAYButton              matlab.ui.control.Button
        UIAxes                  matlab.ui.control.UIAxes
    end


    properties (Access = private)
        startTime = 60;
        timer;
    end

    methods (Access = private)
        function updateTime(app)
            if app.timer.Running
                % Get number of timer cycles elapsed
                elapsedCycles = app.timer.TasksExecuted;
                % Calculate remaining time based on timer period and elapsed cycles
                remainingTime = max(0, app.startTime - elapsedCycles * app.timer.Period);
                minutes = floor(remainingTime / 60);
                seconds = mod(remainingTime, 60);
                timeString = sprintf('%d:%02d', minutes, seconds);
                app.TimeLeftEditField.Value = timeString;
                drawnow;  % Force figure update (optional)
            end
            drawnow;  % Force figure update (optional)
        end

        function timeLeft = getRemainingTime(app)
            % Decrement remaining time by timer period (1 second in this case)
            timeLeft = max(0, app.startTime - toc(app.timer.StartTime));
        end
    end

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            ispy = imread('img/ispy.png');
            imshow(ispy, "Parent", app.UIAxes);

            % Create timer object
            app.timer = timer(...
                'ExecutionMode', 'fixedRate',...  % Execute every period
                'Period', 1, ...                   % Update every second
                'TimerFcn', @(~,~) updateTime(app));
            minutes = floor(app.startTime / 60);
            seconds = mod(app.startTime, 60);
            timeString = sprintf('%d:%02d', minutes, seconds);
            app.TimeLeftEditField.Value = timeString;
        end

        % Button pushed function: PLAYButton
        function PLAYButtonPushed(app, event)
            % Start the timer
            stop(app.timer);
            start(app.timer);
            updateTime(app)

            % IMPORTANT: UPDATE as new images are added
            % String array of all images in the img directory
            images = ["img/gameimages/bedroom.jpg" "img/gameimages/livingroom.jpg" "img/gameimages/clutter.jpeg" "img/gameimages/pinkstudy.jpg" "img/gameimages/boxes.png"]
            index = int8(length(images)*rand)
            % Case for minor bug where index of 0 will cause the program to
            % crash, switches index of 0 to index of 1
            if index == 0
                index = 1
            end
            % Load the image based on the index (replace 'images' with your function)
            imagefile = images(index);
            x = imread(imagefile);

            % Get dimensions of the loaded image
            [imageHeight, imageWidth, ~] = size(x);

            % Load the box image
            boxImage = imread('img/box.png');


            % Resize the box image to a smaller size (adjust scale as needed)
            resizedBox = imresize(boxImage, 0.1);  % Reduces size by half

            % Get dimensions of the resized box image
            [resizedBoxHeight, resizedBoxWidth, ~] = size(resizedBox);

            % Calculate the maximum X and Y coordinates where the box can be placed
            % This ensures the box stays within a reasonable portion of the image
            maxX = imageWidth - resizedBoxWidth;
            maxY = imageHeight - resizedBoxHeight;
            % Generate random X and Y coordinates within the calculated boundaries
            randomX = randi([1, maxX]);
            randomY = randi([1, maxY]);

            % Overlay the box image on the chosen image
            x(randomY:randomY+resizedBoxHeight-1, randomX:randomX+resizedBoxWidth-1, :) = resizedBox;

            % Display the final image with the box
            imshow(x, "Parent", app.UIAxes);
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIAxes = uiaxes(app.UIFigure);
            app.PLAYButton = uibutton(app.UIFigure, 'push');
            app.PLAYButton.ButtonPushedFcn = createCallbackFcn(app, @PLAYButtonPushed, true);
            app.PLAYButton.Text = 'PLAY!';
            app.TimeLeftEditFieldLabel = uilabel(app.UIFigure);
            app.TimeLeftEditFieldLabel.Text = 'Time Left:';
            app.TimeLeftEditField = uieditfield(app.UIFigure, 'text');
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = app1

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end