classdef app1Tristan < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                matlab.ui.Figure
        BoxIcon_2               matlab.ui.control.Image
        UITable                 matlab.ui.control.Table
        NEXTLEVELButton         matlab.ui.control.Button
        BoxIcon                 matlab.ui.control.Image
        TimeLeftEditField       matlab.ui.control.EditField
        TimeLeftEditFieldLabel  matlab.ui.control.Label
        PLAYButton              matlab.ui.control.Button
        UIAxes                  matlab.ui.control.UIAxes
    end


   properties (Access = private)
        levels = struct('image', {}, 'boxLocation', {});
        lastImage = ""; % Variable storing the path to last image encountered, so images don't repeat
        currentLevel = 1;
        startTime = 60;
        timer;
        boxIconHeight = 40;
        boxIconWidth = 40;
        NextLevelButton         matlab.ui.control.Button % Added NextLevelButton
    end

    methods (Access = private)
        function updateTime(app)
            if app.timer.Running
                % Get number of timer cycles elapsed
                elapsedCycles = app.timer.TasksExecuted;
                % Calculate remaining time based on timer period and elapsed cycles
                remainingTime = max(0, app.startTime - elapsedCycles * app.timer.Period);
                minutes = floor(remainingTime / 60);
                seconds = mod(remainingTime, 60);
                timeString = sprintf('%d:%02d', minutes, seconds);
                app.TimeLeftEditField.Value = timeString;
                drawnow;  % Force figure update (optional)
            end
        end

        function loadLevel(app)
            level = app.levels(app.currentLevel);
            imshow(level.image, "Parent", app.UIAxes);

            % Load the box image
            boxImage = imread('img/box.png');

            % Resize the box image to a smaller size (adjust scale as needed)
            resizedBox = imresize(boxImage, 0.1);  % Reduces size by half

            % Get dimensions of the resized box image
            [resizedBoxHeight, resizedBoxWidth, ~] = size(resizedBox);

            % Calculate the maximum X and Y coordinates where the box can be placed
            % This ensures the box stays within a reasonable portion of the image
            maxX = size(level.image, 2) - resizedBoxWidth;
            maxY = size(level.image, 1) - resizedBoxHeight;
            % Generate random X and Y coordinates within the calculated boundaries
            randomX = randi([1, maxX]);
            randomY = randi([1, maxY]);

            [x,y,z] = size(level.image)

            % Use the box location from the current level
            boxLocation = level.boxLocation;

            % Overlay the box image on the chosen image
            hold(app.UIAxes, 'on');
            image(app.UIAxes, boxLocation(1):(boxLocation(1) + resizedBoxWidth - 1), ...
                boxLocation(2):(boxLocation(2) + resizedBoxHeight - 1), resizedBox);
            hold(app.UIAxes, 'off');
        end
    end


    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            ispy = imread('img/ispy.png');
            app.levels(1).image = ispy;
            app.levels(1).boxLocation = [100, 100]; % Example initial box location

            % Create timer object
            app.timer = timer(...
                'ExecutionMode', 'fixedRate',...  % Execute every period
                'Period', 1, ...                   % Update every second
                'TimerFcn', @(~,~) updateTime(app));

            minutes = floor(app.startTime / 60);
            seconds = mod(app.startTime, 60);
            timeString = sprintf('%d:%02d', minutes, seconds);
            app.TimeLeftEditField.Value = timeString;
    
            % Making the box icon, timer, icon view,
            % and next level buttons invisible (outside
            % of the window's bounds) on startup
            app.BoxIcon.Position = [600, 600, 150, 40];
            app.NEXTLEVELButton.Position = [600, 600, 150, 40];
            app.TimeLeftEditField.Position = [600, 600, 150, 40];
            app.TimeLeftEditFieldLabel.Position = [600, 600, 150, 40];
            app.BoxIcon_2.Position = [600,600,79,79];
            app.UITable.Position = [600,600,79,79];
 
            loadLevel(app);

        end

        % Button pushed function: PLAYButton
        function PLAYButtonPushed(app, event)
            % Moving the PLAY! button out of the because it's not necessary
            % after you click it the first time
            app.PLAYButton.Position = [600, 600, 150, 40];
            app.NEXTLEVELButton.Position = [600, 600, 150, 40];
            
            % Moving the icon view and timer view once the video game
            % starts
            app.BoxIcon_2.Position = [21,258,62,79];
            app.UITable.Position = [18,241,68,112];
            app.TimeLeftEditField.Position = [306,363,100,22];
            app.TimeLeftEditFieldLabel.Position = [233,363,58,22];

            % Start the timer
            stop(app.timer);
            start(app.timer);
            updateTime(app);
           
            % IMPORTANT: UPDATE as new images are added
            % String array of all images in the img directory
            images = ["img/gameimages/bedroom.jpg" "img/gameimages/livingroom.jpg" "img/gameimages/clutter.jpeg" "img/gameimages/pinkstudy.jpg" "img/gameimages/boxes.png"];
            index = randi([1, length(images)]);
            
            % Load the image based on the index (replace 'images' with your function)
            imagefile = images(index);
            x = imread(imagefile);

            % Get dimensions of the loaded image
            [imageHeight, imageWidth, ~] = size(x);

            % Load the box image
            boxImage = imread('img/box.png');

            % Calculate the maximum X and Y coordinates where the box can be placed
            % This ensures the box stays within a reasonable portion of the image
            maxX = imageWidth - app.boxIconWidth;
            maxY = imageHeight - app.boxIconHeight;
            % Generate random X and Y coordinates within the calculated boundaries
            randomX = randi([1, maxX]);
            randomY = randi([1, maxY]);
          
            app.BoxIcon.Position = [randomX, randomY, app.boxIconWidth, app.boxIconHeight];
            
            % Display the final image with the box
            imshow(x, "Parent", app.UIAxes);

        disp('Complete the current level first!');
  
function isComplete = isLevelComplete(app)
    isComplete = app.TimeLeftEditField.Value == "0:00";
end

function loadNextLevel(app)
    app.currentLevel = app.currentLevel + 1;
    disp(['Next level loaded: Level ', num2str(app.currentLevel)]);
end
        end

        % Image clicked function: BoxIcon
        function BoxIconImageClicked(app, event)
            % Makes "Next Level" button appear
            app.NEXTLEVELButton.Position = [250, 20, 150, 40];

            % Stops the timer
            stop(app.timer);
        end

        % Button pushed function: NEXTLEVELButton
        function NEXTLEVELButtonPushed(app, event)
            PLAYButtonPushed(app, event);
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            % Get the file path for locating images
            pathToMLAPP = fileparts(mfilename('fullpath'));
            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIAxes = uiaxes(app.UIFigure);
            app.PLAYButton = uibutton(app.UIFigure, 'push');
            app.PLAYButton.ButtonPushedFcn = createCallbackFcn(app, @PLAYButtonPushed, true);
            app.PLAYButton.Text = 'PLAY!';
            app.TimeLeftEditFieldLabel = uilabel(app.UIFigure);
            app.TimeLeftEditFieldLabel.Text = 'Time Left:';
            app.TimeLeftEditField = uieditfield(app.UIFigure, 'text');
            app.BoxIcon = uiimage(app.UIFigure);
            app.BoxIcon.ImageClickedFcn = createCallbackFcn(app, @BoxIconImageClicked, true);
            app.BoxIcon.ImageSource = fullfile(pathToMLAPP, 'img', 'box.png');
            app.NEXTLEVELButton = uibutton(app.UIFigure, 'push');
            app.NEXTLEVELButton.ButtonPushedFcn = createCallbackFcn(app, @NEXTLEVELButtonPushed, true);
            app.NEXTLEVELButton.Text = 'NEXT LEVEL!';
            app.UITable = uitable(app.UIFigure);
            app.UITable.ColumnName = {'---Icon---'};
            app.UITable.RowName = {};
            app.BoxIcon_2 = uiimage(app.UIFigure);
            app.BoxIcon_2.ImageSource = fullfile(pathToMLAPP, 'img', 'box.png');
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = app1Tristan

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end